classdef CovidDataVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationUIFigure  matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        CountryListBoxLabel         matlab.ui.control.Label
        CountryListBox              matlab.ui.control.ListBox
        StateorRegionListBoxLabel   matlab.ui.control.Label
        StateorRegionListBox        matlab.ui.control.ListBox
        DatatoPlotButtonGroup       matlab.ui.container.ButtonGroup
        CasesButton                 matlab.ui.control.RadioButton
        DeathsButton                matlab.ui.control.RadioButton
        BothButton                  matlab.ui.control.RadioButton
        OptionButtonGroup           matlab.ui.container.ButtonGroup
        CumulativeButton            matlab.ui.control.RadioButton
        DailyButton                 matlab.ui.control.RadioButton
        AveragedNumberofDaysSliderLabel  matlab.ui.control.Label
        AveragedNumberofDaysSlider  matlab.ui.control.Slider
    end


    properties (Access = private)
        AveragingWindow                 %Window size to take the average
        AveragedCumulativeCases         %Averaged cumulative cases of a country
        AveragedDailyCases              %Averaged daily cases of a country
        AveragedCumulativeDeaths        %Averaged cumulative deaths of a country
        AveragedDailyDeaths             %Averaged daily deaths of a country
        Countries                       %Vector of country objects
        Country                         %Selected country
        CountryList                     %Vector of country names
        StateList                       %Vector with the states of the selected country
        State                           %Selected state
        Cases                           %Cumulative/ daily cases of the selected country
        Deaths                          %Cumulative/ daily deaths of the selected country
        Dates                           %Vector with the dates in the dataset
        LastMonth                       %Next month after the last date in the dataset
        FirstMonth                      %First month in the dataset
        FirstYear                       %First year in the dataset
        LastYear                        %Last year in the dataset
        ShowCases                       %Plot cumulative/daily cases
        ShowDeaths                      %Plot cumulative/daily deaths
    end

    methods (Access = private)
        %Initializing the properties of the app
        function initialization(app, covid_data)
            app.initialButtonsSettings();
            app.initialSliderSettings();
            app.Countries = [];
            app.CountryList = ["Global"];
            globalcases = zeros(1,size(covid_data,2)-2);
            globaldeaths = zeros(1,size(covid_data,2)-2);
            for ii = 2:size(covid_data,1)
                country = covid_data{ii,1};
                state = covid_data{ii,2};
                cases  = [];
                deaths = [];
                for jj = 3:size(covid_data,2)
                    cases = [cases covid_data{ii,jj}(1)];
                    deaths = [deaths covid_data{ii,jj}(2)];
                end
                countryobj = CovidData(country,state,cases,deaths);
                app.Countries = [app.Countries, countryobj];
                if isempty(state)
                    globalcases = globalcases + cases;
                    globaldeaths = globaldeaths + deaths;
                end
                if ~ismember(string(country),app.CountryList)
                    app.CountryList = [app.CountryList string(country)];
                end
            end
            globalobj = CovidData('Global','All', globalcases, globaldeaths);
            app.Countries = [globalobj app.Countries];
            
                        
            dates = covid_data(1,3:end);
            lastday = dates(end);
            firstday = dates(1);
            lastmonth = month(datetime(lastday,'Format','MM'));
            app.FirstMonth = month(datetime(firstday,'Format','MM'));
            app.LastYear = 2000 + year(datetime(lastday,'Format','yy'));
            app.FirstYear = 2000 + year(datetime(firstday,'Format','yy'));
            if lastmonth == 12
                app.LastMonth = 1;
            else
                app.LastMonth = lastmonth+1;
            end

            app.Dates = datetime(dates,'InputFormat','MM/dd/yy', 'Format','dd/MMM/yy');
            app.Country = 'Global';
            
            
        end
        %Extracting the states of the selected country from the dataset
        function findingstates(app)
            occurences = findobj(app.Countries, 'Country', app.Country);
            app.StateList = ["All"];
            for ii = 2: length(occurences)
                app.StateList = [app.StateList string(occurences(ii).State)];
            end       
            
        end
    
        %Plotting data
        function plotData(app)
            cla(app.UIAxes,'reset')     %resetting the axes                        
            if app.ShowCases
                if app.BothButton.Value             %double y axes plot, selecting the left axis
                    yyaxis(app.UIAxes, 'left')
                end
                b = bar(app.UIAxes,app.Dates,app.Cases);
                ylabel(app.UIAxes,'');
                ax = ancestor(b(1), 'axes');
                yaxis = get(ax,'ytick');
                set(ax, 'YTickLabel', cellstr(num2str(yaxis(:)))); %getting rid of the exponential form of numbers
                hold(app.UIAxes,'on');
            end
            if app.ShowDeaths
                if app.BothButton.Value             %double y axes plot, selecting the right axis
                    yyaxis(app.UIAxes,'right')
                end
                p = plot(app.UIAxes, app.Dates, app.Deaths, 'r', 'LineWidth',1);
                ylabel(app.UIAxes,'');

                ax = ancestor(p(1), 'axes');
                yaxis = get(ax,'ytick');
                set(ax, 'YTickLabel', cellstr(num2str(yaxis(:))));
            end
            hold(app.UIAxes, 'off');
            grid(app.UIAxes,'on');
                        
            xlim(app.UIAxes, [datetime(app.FirstYear,app.FirstMonth,01) datetime(app.LastYear,app.LastMonth,01)])
            xtickformat(app.UIAxes, 'dd-MMM-yyyy')
            xlabel(app.UIAxes, '');
            
            %Selecting the relevant keywords
            %i.e. Cumulative, Daily, Cases, Deaths, Mean days
            if app.CumulativeButton.Value
                firstkeyword = 'Cumulative';
            elseif app.DailyButton.Value
                firstkeyword = 'Daily';
            end
            
            if app.BothButton.Value
                legend(app.UIAxes,'Cases','Deaths', 'Location','NorthWest');
                secondkeyword = 'Cases and Deaths';
            elseif app.CasesButton.Value
                secondkeyword = 'Cases';
            else
                secondkeyword = 'Deaths';
            end
            
            if app.AveragingWindow == 1
                lastkeyword = '';
            else
                lastkeyword = sprintf('(%d-day mean)',app.AveragingWindow);
            end
            
            if isequal(app.Country,'Global')
                title(app.UIAxes, sprintf('%s Number of %s Globally %s',firstkeyword, secondkeyword,lastkeyword));
            elseif isequal(app.State,'All')
                title(app.UIAxes, sprintf('%s Number of %s in %s %s', firstkeyword, secondkeyword, app.Country,lastkeyword));
            else
                title(app.UIAxes, sprintf('%s Number of %s in %s, %s %s',firstkeyword, secondkeyword,app.State,app.Country,lastkeyword));
            end
            
        end
        %Calculating the averaged cumulative & daily cases, cumulative & daily deaths 
        function computingAverage(app)
            object = findobj(app.Countries,'Country',app.Country,'State',app.State)
            if app.CumulativeButton.Value
                app.AveragedCumulativeCases = movmean(object.CumulativeCases, [app.AveragingWindow 0]);
                app.AveragedCumulativeDeaths = movmean(object.CumulativeDeaths, [app.AveragingWindow 0]);
                app.Cases = app.AveragedCumulativeCases;
                app.Deaths = app.AveragedCumulativeDeaths;
            elseif app.DailyButton.Value
                app.AveragedDailyCases  =  movmean(object.DailyCases, [app.AveragingWindow 0]);        
                app.AveragedDailyDeaths = movmean(object.DailyDeaths, [app.AveragingWindow 0]);
                app.Cases = app.AveragedDailyCases;
                app.Deaths = app.AveragedDailyDeaths;
            end
            
        end
        %Resetting the slider to the default position
        function initialSliderSettings(app)
            app.AveragedNumberofDaysSlider.Value = app.AveragedNumberofDaysSlider.Limits(1);
            app.AveragingWindow = app.AveragedNumberofDaysSlider.Value;
        end
        %Resetting all buttons to the defaults positions
        function initialButtonsSettings(app)
            app.BothButton.Value = false;
            app.DeathsButton.Value = false;
            app.CasesButton.Value = true;
            app.CumulativeButton.Value = true;
            app.DailyButton.Value = false;
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load 'Covid19Data.mat'                      %loading data
            app.initialization(covid_data);
            app.CountryListBox.Items = app.CountryList;
            object = findobj(app.Countries, 'Country', app.CountryListBox.Value);
            app.findingstates();
            app.StateorRegionListBox.Items = app.StateList;
            app.Country = object.Country;
            app.State = object.State;
            app.Cases = object.CumulativeCases;
            app.Deaths = object.CumulativeDeaths;
            app.AveragedCumulativeCases = object.CumulativeCases;
            app.AveragedDailyCases = object.DailyCases;
            app.AveragedCumulativeDeaths = object.CumulativeDeaths;
            app.AveragedDailyDeaths = object.DailyDeaths;
            app.plotData();
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.initialButtonsSettings();              %resetting the buttons if the country is changed
            country = app.CountryListBox.Value;
            app.Country = country;
            app.findingstates();
            app.StateorRegionListBox.Items = app.StateList;
            object = findobj(app.Countries, 'Country', country);            
            app.State = object.State;
            if app.CumulativeButton.Value
                app.Cases = object.CumulativeCases;
                app.Deaths = object.CumulativeDeaths;
            elseif app.DailyButton.Value
                app.Cases = object.DailyCases;
                app.Deaths = object.DailyDeaths;
            end
            app.initialSliderSettings();              %resetting the slider if the country is changed
            app.computingAverage();                   %computing the averaged parameters
            app.plotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            state = app.StateorRegionListBox.Value;
            object = findobj(app.Countries,'Country',app.Country,'State', state);  %finding the country with the selected state
            app.Country = object.Country;
            app.State = object.State;
           if app.CumulativeButton.Value
                app.Cases = object.CumulativeCases;
                app.Deaths = object.CumulativeDeaths;
           elseif app.DailyButton.Value
                app.Cases = object.DailyCases;
                app.Deaths = object.DailyDeaths;
           end
           app.computingAverage();
           app.plotData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.plotData();            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            object = findobj(app.Countries,'Country',app.Country,'State',app.State);
            if app.CumulativeButton.Value
                app.Cases = object.CumulativeCases;
                app.Deaths = object.CumulativeDeaths;
                app.computingAverage();
            elseif app.DailyButton.Value
                app.Cases = object.DailyCases;
                app.Deaths = object.DailyDeaths;
                app.computingAverage();
            end
            app.plotData();
        end

        % Value changing function: AveragedNumberofDaysSlider
        function AveragedNumberofDaysSliderValueChanging(app, event)
            app.AveragingWindow = ceil(event.Value);
            app.computingAverage();
            app.plotData();            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationUIFigure = uifigure;
            app.COVID19DataVisualizationUIFigure.Name = 'COVID-19 Data Visualization ';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CountryListBoxLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.StateorRegionListBoxLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedNumberofDaysSliderLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.AveragedNumberofDaysSliderLabel.Text = 'Averaged Number of Days';
            app.AveragedNumberofDaysSlider = uislider(app.COVID19DataVisualizationUIFigure);
            app.AveragedNumberofDaysSlider.Limits = [1 15];
            app.AveragedNumberofDaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedNumberofDaysSliderValueChanging, true);
            app.AveragedNumberofDaysSlider.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = CovidDataVisualization

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationUIFigure)
        end
    end
end